classdef ReperFrene < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ztLabel           matlab.ui.control.Label
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        ytEditField       matlab.ui.control.EditField
        ytEditFieldLabel  matlab.ui.control.Label
        xtEditField       matlab.ui.control.EditField
        xtEditFieldLabel  matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
syms t;
syms x y z;
syms tau n b;
            t = 0:0.05:2*pi;
            x=app.xtEditField.Value;
            y=app.ytEditField.Value;
            z=t;
         x=sin(t);
         y=cos(2*t);
         z=t;
        plot3(app.UIAxes,x,y,z)
        hold on


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
syms t ;
syms r x y z;
syms tau n b;
            x=app.xtEditField.Value;
            y=app.ytEditField.Value;
            z=t;
       
          x=string(x);
           y=string(y);
           z=string(z);
         
            r=[x y z];
            r=str2sym(r);
            dr = diff(r);
d2r = diff(r,2,t);
c1 = cross(dr,d2r);
c2 = cross(c1,dr);
tau= dr/sqrt(dot(dr,dr));%находим единичные вектор касательной
n= c2/sqrt(dot(c2,c2));%находим единичный вектор главной нормали
b= c1/sqrt(dot(c1,c1));%находим единичный вектор бинормали

 assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')
    
    % Set up figure handle visibility, run ginput, and return state
    fhv = app.UIFigure.HandleVisibility;        % Current status
    app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
    set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
    
    [k,l,m] = ginput(1);                        % run ginput
    
    app.UIFigure.HandleVisibility = fhv;        % return original state
    
plot3(app.UIAxes,k,l,m,'MarkerSize',20,'Color','black')
hold on

tau=subs(tau,t,m)%Здесь вместо 1 должна стоять m
n=subs(n,t,m)
b=subs(b,t,m)


plot3(app.UIAxes,tau(1,1),tau(1,2),tau(1,3),'Color','r')
hold on
plot3(app.UIAxes,n(1,1),n(1,2),n(1,3),'Color','g')
hold on
plot3(app.UIAxes,b(1,1),b(1,2),b(1,3),'Color','b')
hold on

%coor1=[k tau(1,1)]
%coor2=[l tau(1,2)]
%coor3=[m tau (1,3)]
%plot3(app.UIAxes,coor1,coor2,coor3,'Color,'r')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Репер Френе'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.xtEditFieldLabel = uilabel(app.UIFigure);
            app.xtEditFieldLabel.Text = 'x(t)';
            app.xtEditField = uieditfield(app.UIFigure, 'text');
            app.ytEditFieldLabel = uilabel(app.UIFigure);
            app.ytEditFieldLabel.Text = 'y(t)';
            app.ytEditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Начертить кривую';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Нарисовать Репер Френе';
            app.ztLabel = uilabel(app.UIFigure);
            app.ztLabel.Text = 'z= t';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReperFrene

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end