classdef ReperFrene < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        xtEditFieldLabel  matlab.ui.control.Label
        xtEditField       matlab.ui.control.EditField
        ytEditFieldLabel  matlab.ui.control.Label
        ytEditField       matlab.ui.control.EditField
        Button            matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        tEditFieldLabel   matlab.ui.control.Label
        tEditField        matlab.ui.control.EditField
        ztEditFieldLabel  matlab.ui.control.Label
        ztEditField       matlab.ui.control.EditField
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
syms t;
            
            x=app.xtEditField.Value;
            y=app.ytEditField.Value;
            z=app.ztEditField.Value;
        x=str2sym(x);
        y=str2sym(y);
        z=str2sym(z);
        
        fplot3(app.UIAxes,x,y,z,'Color','black')
        hold(app.UIAxes,"on")
        
             k=app.tEditField.Value;
             k=str2sym(k);
                
        x1=subs(x,t,k);
        y1=subs(y,t,k);
        z1=subs(z,t,k);
      
      
     K=[x1 y1 z1];
        fplot3(app.UIAxes,K(1,1),K(1,2),K(1,3),'Marker','*','MarkerSize',10,'Color','black');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
syms t;
            x=app.xtEditField.Value;
            y=app.ytEditField.Value;
            z=app.ztEditField.Value;
       
          x=string(x);
           y=string(y);
           z=string(z);
         
            r=[x y z];
            r=str2sym(r);
            dr = diff(r);
d2r = diff(dr);
c1 = cross(dr,d2r);
c2 = cross(c1,dr);
tau= dr/sqrt(dot(dr,dr));%находим единичные вектор касательной
n= c2/sqrt(dot(c2,c2));%находим единичный вектор главной нормали
b= c1/sqrt(dot(c1,c1));%находим единичный вектор бинормали

k=app.tEditField.Value;
k=string(k);
k=str2sym(k);


tau=subs(tau,t,k)
n=subs(n,t,k)
b=subs(b,t,k)

            x=app.xtEditField.Value;
            y=app.ytEditField.Value;
            z=app.ztEditField.Value;
        x=str2sym(x);
        y=str2sym(y);
        z=str2sym(z);
        
        fplot3(app.UIAxes,x,y,z)
       
        
             k=app.tEditField.Value;
             k=str2sym(k);
                
        x1=subs(x,t,k);
        y1=subs(y,t,k);
        z1=subs(z,t,k);


     K=[x1 y1 z1];
    

coor11=[tau(1,1) K(1,1)];
coor12=[tau(1,2) K(1,2)];
coor13=[tau(1,2) K(1,3)];

coor21=[n(1,1) K(1,1)];
coor22=[n(1,2) K(1,2)];
coor23=[n(1,2) K(1,3)];

coor31=[b(1,1) K(1,1)];
coor32=[b(1,2) K(1,2)];
coor33=[b(1,2) K(1,3)];

line(app.UIAxes,coor11,coor12,coor13,'Color','r')

line(app.UIAxes,coor21,coor22,coor23,'Color','g')

line(app.UIAxes,coor31,coor32,coor33,'Color','b')


hold(app.UIAxes,"off")


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xtEditFieldLabel = uilabel(app.UIFigure);
            app.xtEditFieldLabel.Text = 'x(t)';
            app.xtEditField = uieditfield(app.UIFigure, 'text');
            app.ytEditFieldLabel = uilabel(app.UIFigure);
            app.ytEditFieldLabel.Text = 'y(t)';
            app.ytEditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Начертить кривую';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Нарисовать Репер Френе';
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'text');
            app.ztEditFieldLabel = uilabel(app.UIFigure);
            app.ztEditFieldLabel.Text = 'z(t)';
            app.ztEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Репер Френе'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReperFrene

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end